classdef BEIFI_SoftwareModelo1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Software1Panel        matlab.ui.container.Panel
        Image6                matlab.ui.control.Image
        ModeloSimpleLabel     matlab.ui.control.Label
        DatosPanel            matlab.ui.container.Panel
        AEditFieldLabel       matlab.ui.control.Label
        A_dato                matlab.ui.control.NumericEditField
        C10EditFieldLabel     matlab.ui.control.Label
        C10_dato              matlab.ui.control.NumericEditField
        VocLabel              matlab.ui.control.Label
        Voc_dato              matlab.ui.control.NumericEditField
        RoEditFieldLabel      matlab.ui.control.Label
        Ro_dato               matlab.ui.control.NumericEditField
        TiempoLabel           matlab.ui.control.Label
        tiempo_dato           matlab.ui.control.NumericEditField
        TamaodepasoKnobLabel  matlab.ui.control.Label
        paso_dato             matlab.ui.control.Knob
        GraficasPanel         matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        ControlesPanel        matlab.ui.container.Panel
        LimpiarButton         matlab.ui.control.Button
        CalcularButton        matlab.ui.control.Button
        AcercadeButton        matlab.ui.control.Button
        Button                matlab.ui.control.Button
        AtrasButton           matlab.ui.control.Button
        Image5                matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AtrasButton
        function AtrasButtonPushed(app, event)
            BEIFI_SoftwareMenu; 
            closereq;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            uialert(app.UIFigure, 'Paso 1: Introducir valores en las cajas de texto         Paso 2: Presionar boton calcular                                             Paso 3: Contemplar los resultados', 'Achuda', 'Icon', 'question');
        end

        % Button pushed function: AcercadeButton
        function AcercadeButtonPushed(app, event)
            uialert(app.UIFigure, 'El presente programa fue desarrollado por el estudiante Jesús Osvaldo Sandoval S. (Jezaah Oswaldosky) con fines de acreditar un programa de becas llamado BEIFI de su escuela. Incluyendo que su desarrollo fue tambien para aprender sobre distintos modelos de cargas y descarga de baterias y su utilidad en la industria desde el campo electrico hasta el automotriz.', 'Acerca de...', 'Icon', 'info');
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            %   Reiniciamos cada uno de los valores de los textfield's... 
            app.A_dato.Value = 0;
            app.C10_dato.Value = 0;
            app.Ro_dato.Value = 0;
            app.Voc_dato = 0; 
            app.tiempo_dato.Value = 0;
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            %   Configuramos variables para determinar el modelo
            A_input =  app.A_dato.Value; 
            C10_input = app.C10_dato.Value; 
            Voc_input =  app.Voc_dato.Value;
            Ro_input = app.Ro_dato.Value; 
            tiempo_input = app.tiempo_dato.Value; 
            step_input =  app.paso_dato.Value;
            
            %   Determinamos los pasos de tiempo
            t =  0:step_input:tiempo_input-step_input; 
            
            %   Hacemos los calculos para S, Rint e It
            S = 1-(A_input/C10_input)*t;    %% Determinamos el valor S
            
            Rint = Ro_input./S;         %% Determinamos el valor de la resistencia interna de la bateria
            
            It =  Voc_input./Rint;      %% Determinamos la corriente de la bateria, con respecto a la resistencia interna de la bateria
            
            %%  Senal de calix 
%             x =  sin(2*pi*t); 
            %   Graficamos el valor de la resistencia de la bateria 
            plot(app.UIAxes,t,Rint);
            %   Graficamos la corriente de la bateria con respecto al
            plot(app.UIAxes2,t,It);
        end

        % Value changed function: paso_dato
        function paso_datoValueChanged(app, event)
            value = app.paso_dato.Value;
            
        end

        % Value changing function: paso_dato
        function paso_datoValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Software1Panel = uipanel(app.UIFigure);
            app.Software1Panel.Title = 'Software 1';
            app.Image6 = uiimage(app.Software1Panel);
            app.Image6.ImageSource = 'citedi_logo.png';
            app.ModeloSimpleLabel = uilabel(app.Software1Panel);
            app.ModeloSimpleLabel.Text = 'Modelo Simple ';
            app.DatosPanel = uipanel(app.UIFigure);
            app.DatosPanel.Title = 'Datos';
            app.AEditFieldLabel = uilabel(app.DatosPanel);
            app.AEditFieldLabel.Text = 'A';
            app.A_dato = uieditfield(app.DatosPanel, 'numeric');
            app.C10EditFieldLabel = uilabel(app.DatosPanel);
            app.C10EditFieldLabel.Text = 'C10';
            app.C10_dato = uieditfield(app.DatosPanel, 'numeric');
            app.VocLabel = uilabel(app.DatosPanel);
            app.VocLabel.Text = 'Voc';
            app.Voc_dato = uieditfield(app.DatosPanel, 'numeric');
            app.RoEditFieldLabel = uilabel(app.DatosPanel);
            app.RoEditFieldLabel.Text = 'Ro';
            app.Ro_dato = uieditfield(app.DatosPanel, 'numeric');
            app.TiempoLabel = uilabel(app.DatosPanel);
            app.TiempoLabel.Text = 'Tiempo';
            app.tiempo_dato = uieditfield(app.DatosPanel, 'numeric');
            app.TamaodepasoKnobLabel = uilabel(app.DatosPanel);
            app.TamaodepasoKnobLabel.Text = 'Tamaño de paso';
            app.paso_dato = uiknob(app.DatosPanel, 'continuous');
            app.paso_dato.Limits = [0 0.5];
            app.paso_dato.ValueChangedFcn = createCallbackFcn(app, @paso_datoValueChanged, true);
            app.paso_dato.ValueChangingFcn = createCallbackFcn(app, @paso_datoValueChanging, true);
            app.GraficasPanel = uipanel(app.UIFigure);
            app.GraficasPanel.Title = 'Graficas';
            app.UIAxes = uiaxes(app.GraficasPanel);
            title(app.UIAxes, 'Valor de la Rint de la bateria')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Rint')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GraficasPanel);
            title(app.UIAxes2, 'Corriente Iout de la bateria')
            xlabel(app.UIAxes2, 'Tiempo')
            ylabel(app.UIAxes2, 'Iout')
            zlabel(app.UIAxes2, 'Z')
            app.ControlesPanel = uipanel(app.UIFigure);
            app.ControlesPanel.Title = 'Controles';
            app.LimpiarButton = uibutton(app.ControlesPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.CalcularButton = uibutton(app.ControlesPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.AcercadeButton = uibutton(app.ControlesPanel, 'push');
            app.AcercadeButton.ButtonPushedFcn = createCallbackFcn(app, @AcercadeButtonPushed, true);
            app.AcercadeButton.Text = 'Acerca de';
            app.Button = uibutton(app.ControlesPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.AtrasButton = uibutton(app.ControlesPanel, 'push');
            app.AtrasButton.ButtonPushedFcn = createCallbackFcn(app, @AtrasButtonPushed, true);
            app.AtrasButton.Text = 'Atras';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'IPN_logo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEIFI_SoftwareModelo1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end