classdef BEIFI_SoftwareModelo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Software2Panel               matlab.ui.container.Panel
        Image6                       matlab.ui.control.Image
        ModeloSimpleModificadoLabel  matlab.ui.control.Label
        DatosPanel                   matlab.ui.container.Panel
        TamaodepasoKnobLabel         matlab.ui.control.Label
        paso_dato                    matlab.ui.control.Knob
        VoLabel                      matlab.ui.control.Label
        Vo_datos                     matlab.ui.control.NumericEditField
        SOCLabel                     matlab.ui.control.Label
        SOC_datos                    matlab.ui.control.NumericEditField
        RoLabel                      matlab.ui.control.Label
        Ro_datos                     matlab.ui.control.NumericEditField
        ILabel                       matlab.ui.control.Label
        I_datos                      matlab.ui.control.NumericEditField
        ALabel                       matlab.ui.control.Label
        A_datos                      matlab.ui.control.NumericEditField
        BLabel                       matlab.ui.control.Label
        B_datos                      matlab.ui.control.NumericEditField
        TiempoLabel                  matlab.ui.control.Label
        Tiempo_datos                 matlab.ui.control.NumericEditField
        GraficasPanel                matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        ControlesPanel               matlab.ui.container.Panel
        LimpiarButton                matlab.ui.control.Button
        CalcularButton               matlab.ui.control.Button
        AcercadeButton               matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        AtrasButton                  matlab.ui.control.Button
        Image5                       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AtrasButton
        function AtrasButtonPushed(app, event)
            BEIFI_SoftwareMenu; 
            closereq;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            uialert(app.UIFigure, 'Paso 1: Introducir valores en las cajas de texto         Paso 2: Presionar boton calcular                                             Paso 3: Contemplar los resultados', 'Achuda', 'Icon', 'question');
        end

        % Button pushed function: AcercadeButton
        function AcercadeButtonPushed(app, event)
            uialert(app.UIFigure, 'El presente programa fue desarrollado por el estudiante Jesús Osvaldo Sandoval S. (Jezaah Oswaldosky) con fines de acreditar un programa de becas llamado BEIFI de su escuela. Incluyendo que su desarrollo fue tambien para aprender sobre distintos modelos de cargas y descarga de baterias y su utilidad en la industria desde el campo electrico hasta el automotriz.', 'Acerca de...', 'Icon', 'info');
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            %   Reiniciamos cada uno de los valores de los textfield's... 
            app.Vo_datos.Value = 0; 
            app.SOC_datos.Value = 0; 
            app.Ro_datos.Value = 0; 
            app.I_datos.Value = 0; 
            app.A_datos.Value = 0; 
            app.B_datos.Value = 0;
            app.Tiempo_datos =  0;
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            %   Configuramos variables para determinar el modelo
            Vo_input =  app.Vo_datos.Value; 
            Ro_input =  app.Ro_datos.Value; 
            A_input = app.A_datos.Value; 
            B_input =  app.B_datos.Value;
           
            step_input =  app.paso_dato.Value;
            
            %   Configuramos las condiciones iniciales 
            I_input =  app.I_datos.Value; 
            SOC_input =  app.SOC_datos.Value; 
            
            %   Definimos las funciones 
            voc_soc = @(SOC_input) Vo_input + A_input*SOC_input;
            rint_soc = @(SOC_input) Ro_input + B_input*SOC_input;


            %   Configuramos el vector tiempo 
            t_input = app.Tiempo_datos.Value; 
            t = 0:step_input:t_input-step_input;
            DeltaV_aging = 0.01*t; 
            
            vt_initial = voc_soc(SOC_input) - I_input * rint_soc(SOC_input)-DeltaV_aging;
            
            Rint_plotable = (vt_initial -voc_soc(SOC_input)+DeltaV_aging)/I_input;
            
            if(Rint_plotable < 0)
                Rint_plotable = Rint_plotable*(-1);
            else 
                Rint_plotable = Rint_plotable;
            end
            %   Configuramos un vector para el estado de carga de la
            %   bateria SOC 
%             soc_values = SOC_input:-step_input:0;
            
% %             %   Calculamos los valores de vt para seer graficados... 
% %             i = 1;
% %             for soc = soc_values
% %                 vt = voc_soc(soc) - I_input * rint_soc(soc);
% %                 volt_t(i) = vt;
% %                 i = i+1; 
% %             end
% %             t =  length(vt); 
% %             t =  0:0.1:t; 
             plot(app.UIAxes,t,vt_initial );
             plot(app.UIAxes2,Rint_plotable);


        end

        % Value changed function: paso_dato
        function paso_datoValueChanged(app, event)
            value = app.paso_dato.Value;
            
        end

        % Value changing function: paso_dato
        function paso_datoValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Software2Panel = uipanel(app.UIFigure);
            app.Software2Panel.Title = 'Software 2';
            app.Image6 = uiimage(app.Software2Panel);
            app.Image6.ImageSource = 'citedi_logo.png';
            app.ModeloSimpleModificadoLabel = uilabel(app.Software2Panel);
            app.ModeloSimpleModificadoLabel.Text = 'Modelo Simple Modificado';
            app.DatosPanel = uipanel(app.UIFigure);
            app.DatosPanel.Title = 'Datos';
            app.TamaodepasoKnobLabel = uilabel(app.DatosPanel);
            app.TamaodepasoKnobLabel.Text = 'Tamaño de paso';
            app.paso_dato = uiknob(app.DatosPanel, 'continuous');
            app.paso_dato.Limits = [0 0.5];
            app.paso_dato.ValueChangedFcn = createCallbackFcn(app, @paso_datoValueChanged, true);
            app.paso_dato.ValueChangingFcn = createCallbackFcn(app, @paso_datoValueChanging, true);
            app.VoLabel = uilabel(app.DatosPanel);
            app.VoLabel.Text = 'Vo';
            app.Vo_datos = uieditfield(app.DatosPanel, 'numeric');
            app.SOCLabel = uilabel(app.DatosPanel);
            app.SOCLabel.Text = 'SOC';
            app.SOC_datos = uieditfield(app.DatosPanel, 'numeric');
            app.RoLabel = uilabel(app.DatosPanel);
            app.RoLabel.Text = 'Ro';
            app.Ro_datos = uieditfield(app.DatosPanel, 'numeric');
            app.ILabel = uilabel(app.DatosPanel);
            app.ILabel.Text = 'I';
            app.I_datos = uieditfield(app.DatosPanel, 'numeric');
            app.ALabel = uilabel(app.DatosPanel);
            app.ALabel.Text = 'A';
            app.A_datos = uieditfield(app.DatosPanel, 'numeric');
            app.BLabel = uilabel(app.DatosPanel);
            app.BLabel.Text = 'B';
            app.B_datos = uieditfield(app.DatosPanel, 'numeric');
            app.TiempoLabel = uilabel(app.DatosPanel);
            app.TiempoLabel.Text = 'Tiempo';
            app.Tiempo_datos = uieditfield(app.DatosPanel, 'numeric');
            app.GraficasPanel = uipanel(app.UIFigure);
            app.GraficasPanel.Title = 'Graficas';
            app.UIAxes = uiaxes(app.GraficasPanel);
            title(app.UIAxes, 'Voltaje de la bateria')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Voltaje')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GraficasPanel);
            title(app.UIAxes2, 'Resistencia interna de la bateria')
            xlabel(app.UIAxes2, 'Tiempo')
            ylabel(app.UIAxes2, 'Resistividad')
            zlabel(app.UIAxes2, 'Z')
            app.ControlesPanel = uipanel(app.UIFigure);
            app.ControlesPanel.Title = 'Controles';
            app.LimpiarButton = uibutton(app.ControlesPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.CalcularButton = uibutton(app.ControlesPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.AcercadeButton = uibutton(app.ControlesPanel, 'push');
            app.AcercadeButton.ButtonPushedFcn = createCallbackFcn(app, @AcercadeButtonPushed, true);
            app.AcercadeButton.Text = 'Acerca de';
            app.Button = uibutton(app.ControlesPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.AtrasButton = uibutton(app.ControlesPanel, 'push');
            app.AtrasButton.ButtonPushedFcn = createCallbackFcn(app, @AtrasButtonPushed, true);
            app.AtrasButton.Text = 'Atras';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'IPN_logo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEIFI_SoftwareModelo2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end