classdef BEIFI_SoftwareModelo3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Software3Panel                matlab.ui.container.Panel
        Image6                        matlab.ui.control.Image
        ModeloSimpleModificado2Label  matlab.ui.control.Label
        DatosPanel                    matlab.ui.container.Panel
        TamaodepasoKnobLabel          matlab.ui.control.Label
        paso_dato                     matlab.ui.control.Knob
        VocLabel                      matlab.ui.control.Label
        Vo_datos                      matlab.ui.control.NumericEditField
        SOCLabel                      matlab.ui.control.Label
        SOC_datos                     matlab.ui.control.NumericEditField
        RintLabel                     matlab.ui.control.Label
        Rint_datos                    matlab.ui.control.NumericEditField
        KLabel                        matlab.ui.control.Label
        K_datos                       matlab.ui.control.NumericEditField
        IcargaLabel                   matlab.ui.control.Label
        Ichar_datos                   matlab.ui.control.NumericEditField
        IdescargaLabel                matlab.ui.control.Label
        Idischar_datos                matlab.ui.control.NumericEditField
        TiempoLabel                   matlab.ui.control.Label
        Tiempo_datos                  matlab.ui.control.NumericEditField
        GraficasPanel                 matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        ControlesPanel                matlab.ui.container.Panel
        LimpiarButton                 matlab.ui.control.Button
        CalcularButton                matlab.ui.control.Button
        AcercadeButton                matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        AtrasButton                   matlab.ui.control.Button
        Image5                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AtrasButton
        function AtrasButtonPushed(app, event)
            BEIFI_SoftwareMenu; 
            closereq;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            uialert(app.UIFigure, 'Paso 1: Introducir valores en las cajas de texto         Paso 2: Presionar boton calcular                                             Paso 3: Contemplar los resultados', 'Achuda', 'Icon', 'question');
        end

        % Button pushed function: AcercadeButton
        function AcercadeButtonPushed(app, event)
            uialert(app.UIFigure, 'El presente programa fue desarrollado por el estudiante Jesús Osvaldo Sandoval S. (Jezaah Oswaldosky) con fines de acreditar un programa de becas llamado BEIFI de su escuela. Incluyendo que su desarrollo fue tambien para aprender sobre distintos modelos de cargas y descarga de baterias y su utilidad en la industria desde el campo electrico hasta el automotriz.', 'Acerca de...', 'Icon', 'info');
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            %   Reiniciamos cada uno de los valores de los textfield's... 
           app.Vo_datos.Value = 0; 
           app.SOC_datos.Value = 0;
           app.Rint_datos.Value =  0; 
           app.K_datos.Value = 0; 
           app.Ichar_datos.Value = 0; 
           app.Idischar_datos.Value = 0;
           app.Tiempo_datos.Value = 0;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if(app.paso_dato == 0)
               uialert(app.UIFigure, 'Requiere incorporar una pasod de muestro, te recomendamos girar el actuador knob para correr la simulación. ', 'Alerta', 'Icon', 'warning');
            else 
                    
                %   Configuramos variables para determinar el modelo
                Vo_input = app.Vo_datos.Value;  
                SOC_input = app.SOC_datos.Value; 
                Rint_input = app.Rint_datos.Value;  
                K_input = app.K_datos.Value; 
                
                %   Registramos el tiempo y el tiempo de paso
                tiempo_input = app.Tiempo_datos.Value; 
                step_input =  app.paso_dato.Value;
                
                %   Capturamos los valores de carga y descarga iniciales 
                Ichar_input =  app.Ichar_datos.Value; 
                Idischar_input = app.Idischar_datos.Value; 
                
                %   Determinamos los pasos de tiempo
                t =  0:step_input:3*tiempo_input-step_input; 
                
                %   Inicializamos el voltaje de carga  descarga conforme al
                %   tamano del tiempo
                V_t_charge = zeros(size(t));
                V_t_discharge = zeros(size(t));
                
                %   Realizamos la simulacion de la carga 
                for i =  1: length(t)
                    %   Actualizamos el SOC basado en la corriente de carga
                    SOC_input =  SOC_input + (Ichar_input/3600);
                    %   Calculamos el voltaje de la terminal de salida 
                    V_t_charge(i) = Vo_input - (Rint_input+K_input/SOC_input)*Ichar_input;
                end
                
                SOC_input = app.SOC_datos.Value;
                %   Realizamos la simulacion de la descarga
                for i =  1: length(t)
                    %   Actualizamos el SOC basado sobre la corriente de carga 
                    SOC_input =  SOC_input + (Idischar_input/3600);
                    %   Calculamos el voltaje de la terminal de salida 
                    V_t_discharge(i) = Vo_input + (Rint_input+K_input/SOC_input)*Idischar_input;
                end
                
                %   Graficamos el valor de la resistencia de la bateria 
                plot(app.UIAxes,t,V_t_charge);
                %   Graficamos la corriente de la bateria con respecto al
                plot(app.UIAxes2,t,V_t_discharge);
            end
        end

        % Value changed function: paso_dato
        function paso_datoValueChanged(app, event)
            value = app.paso_dato.Value;
            
        end

        % Value changing function: paso_dato
        function paso_datoValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Software3Panel = uipanel(app.UIFigure);
            app.Software3Panel.Title = 'Software 3';
            app.Image6 = uiimage(app.Software3Panel);
            app.Image6.ImageSource = 'citedi_logo.png';
            app.ModeloSimpleModificado2Label = uilabel(app.Software3Panel);
            app.ModeloSimpleModificado2Label.Text = 'Modelo Simple Modificado 2';
            app.DatosPanel = uipanel(app.UIFigure);
            app.DatosPanel.Title = 'Datos';
            app.TamaodepasoKnobLabel = uilabel(app.DatosPanel);
            app.TamaodepasoKnobLabel.Text = 'Tamaño de paso';
            app.paso_dato = uiknob(app.DatosPanel, 'continuous');
            app.paso_dato.Limits = [0 0.5];
            app.paso_dato.ValueChangedFcn = createCallbackFcn(app, @paso_datoValueChanged, true);
            app.paso_dato.ValueChangingFcn = createCallbackFcn(app, @paso_datoValueChanging, true);
            app.VocLabel = uilabel(app.DatosPanel);
            app.VocLabel.Text = 'Voc';
            app.Vo_datos = uieditfield(app.DatosPanel, 'numeric');
            app.SOCLabel = uilabel(app.DatosPanel);
            app.SOCLabel.Text = 'SOC';
            app.SOC_datos = uieditfield(app.DatosPanel, 'numeric');
            app.RintLabel = uilabel(app.DatosPanel);
            app.RintLabel.Text = 'Rint';
            app.Rint_datos = uieditfield(app.DatosPanel, 'numeric');
            app.KLabel = uilabel(app.DatosPanel);
            app.KLabel.Text = 'K';
            app.K_datos = uieditfield(app.DatosPanel, 'numeric');
            app.IcargaLabel = uilabel(app.DatosPanel);
            app.IcargaLabel.Text = 'I carga';
            app.Ichar_datos = uieditfield(app.DatosPanel, 'numeric');
            app.IdescargaLabel = uilabel(app.DatosPanel);
            app.IdescargaLabel.Text = 'I descarga';
            app.Idischar_datos = uieditfield(app.DatosPanel, 'numeric');
            app.TiempoLabel = uilabel(app.DatosPanel);
            app.TiempoLabel.Text = 'Tiempo';
            app.Tiempo_datos = uieditfield(app.DatosPanel, 'numeric');
            app.GraficasPanel = uipanel(app.UIFigure);
            app.GraficasPanel.Title = 'Graficas';
            app.UIAxes = uiaxes(app.GraficasPanel);
            title(app.UIAxes, 'Valor de la Rint de la bateria')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Rint')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GraficasPanel);
            title(app.UIAxes2, 'Corriente Iout de la bateria')
            xlabel(app.UIAxes2, 'Tiempo')
            ylabel(app.UIAxes2, 'Iout')
            zlabel(app.UIAxes2, 'Z')
            app.ControlesPanel = uipanel(app.UIFigure);
            app.ControlesPanel.Title = 'Controles';
            app.LimpiarButton = uibutton(app.ControlesPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.CalcularButton = uibutton(app.ControlesPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.AcercadeButton = uibutton(app.ControlesPanel, 'push');
            app.AcercadeButton.ButtonPushedFcn = createCallbackFcn(app, @AcercadeButtonPushed, true);
            app.AcercadeButton.Text = 'Acerca de';
            app.Button = uibutton(app.ControlesPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.AtrasButton = uibutton(app.ControlesPanel, 'push');
            app.AtrasButton.ButtonPushedFcn = createCallbackFcn(app, @AtrasButtonPushed, true);
            app.AtrasButton.Text = 'Atras';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'IPN_logo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEIFI_SoftwareModelo3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end